classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InputSignalDropDown            matlab.ui.control.DropDown
        InputSignalDropDownLabel       matlab.ui.control.Label
        AlltherestinputfunctionsarefixedLabel  matlab.ui.control.Label
        FSimpleSinewaveEditField       matlab.ui.control.NumericEditField
        FSimpleSinewaveEditFieldLabel  matlab.ui.control.Label
        DesignFilterButton             matlab.ui.control.Button
        MagnitudedBEditField           matlab.ui.control.NumericEditField
        MagnitudedBEditFieldLabel      matlab.ui.control.Label
        Fstop1EditField                matlab.ui.control.NumericEditField
        Fstop1EditFieldLabel           matlab.ui.control.Label
        Fpass1EditField                matlab.ui.control.NumericEditField
        Fpass1EditFieldLabel           matlab.ui.control.Label
        FilterSpecificationsLabel      matlab.ui.control.Label
        OrderEditField                 matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        FsHzEditField                  matlab.ui.control.NumericEditField
        FsHzEditFieldLabel             matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignFilterButton
        function DesignFilter(app, event)
            % Check if all required inputs are present
            if isempty(app.OrderEditField.Value) || ...
               isempty(app.FsHzEditField.Value) || ...
               isempty(app.MagnitudedBEditField.Value) || ...
               isempty(app.Fpass1EditField.Value) || ...
               isempty(app.Fstop1EditField.Value) || ...
               isempty(app.FilterTypeDropDown.Value)
                % Display an error message and return
                errordlg('Please provide all required inputs.', 'Input Error');
                return;
            end
        
            % Get user inputs from GUI
            filterOrder = app.OrderEditField.Value;
            samplingFrequency = app.FsHzEditField.Value;
            magnitude = app.MagnitudedBEditField.Value;
            Fpass1 = app.Fpass1EditField.Value;
            Fstop1 = app.Fstop1EditField.Value;
            inputSignal=app.InputSignalDropDown.Value;
            FsineWave=app.FSimpleSinewaveEditField.Value;
        
            % Determine the selected filter type
            filterType = app.FilterTypeDropDown.Value;
        
            % Design the filter based on the selected type using fir1
            if strcmpi(filterType, 'low-pass')
                % Design low-pass filter using fir1
                b = fir1(filterOrder, Fpass1/(samplingFrequency/2), 'low', kaiser(filterOrder+1, magnitude));
                a = 1; % Denominator coefficients for fir1 are always 1
            elseif strcmpi(filterType, 'high-pass')
                % Design high-pass filter using fir1
                b = fir1(filterOrder, Fpass1/(samplingFrequency/2), 'high', kaiser(filterOrder+1, magnitude));
                a = 1;
            elseif strcmpi(filterType, 'band-pass')
                % Check if all required inputs are present
                if isempty(app.Fstop1EditField.Value)
                    % Display an error message and return
                    errordlg('Please provide all required inputs.', 'Input Error');
                    return;
                end
                % Design band-pass filter using fir1
                b = fir1(filterOrder, [Fpass1 Fstop1]/(samplingFrequency/2), 'band', kaiser(filterOrder+1, magnitude));
                a = 1;
            elseif strcmpi(filterType, 'band-stop')
                if isempty(app.Fstop1EditField.Value)
                    % Display an error message and return
                    errordlg('Please provide all required inputs.', 'Input Error');
                    return;
                end
                % Design band-stop filter using fir1
                b = fir1(filterOrder, [Fpass1 Fstop1]/(samplingFrequency/2), 'stop', kaiser(filterOrder+1, magnitude));
                a = 1;
            else
                % Handle unsupported filter type
                disp('Unsupported filter type selected.');
                return;
            end
            
            % Frequency response analysis
            [H, Freq] = freqz(b, a, 'half', 1000, samplingFrequency);
            
            % Visualize the filter response on the UIAxes
            plot(app.UIAxes, Freq, 20*log10(abs(H)));
            title(app.UIAxes, 'Magnitude Response');
            xlabel(app.UIAxes, 'Frequency (Hz)');
            ylabel(app.UIAxes, 'Magnitude (dB)');
            grid(app.UIAxes, 'on');

            %Producing input signal
            % Simple Sine Wave
            % Chirp Signal
            % Sine wave with increasing frequency
            % Sawtooth with modulating frequency
            % Rect pulse train with varying frequency
            % Square Wave with modulating Frequency
            if strcmpi(inputSignal, 'Simple Sine Wave')
                t = 0:1/samplingFrequency:1; % Time vector
                f_input = FsineWave; % Frequency of the input sine wave
                x = sin(2*pi*f_input*t); % Input sine wave
            elseif strcmpi(inputSignal, 'Chirp Signal')
                t = 0:1/samplingFrequency:1; % Time vector
                f_start = 100; % Starting frequency of the chirp
                f_end = 500; % Ending frequency of the chirp
                x = chirp(t, f_start, 1, f_end, 'linear');
            elseif strcmpi(inputSignal, 'Sine wave with increasing frequency')
                t = 0:1/samplingFrequency:1;
                f_start = 100; % Starting frequency
                f_end = 500;   % Ending frequency
                f = linspace(f_start, f_end, length(t));
                x = sin(2*pi*f.*t);
            elseif strcmpi(inputSignal, 'Sawtooth with modulating frequency')
                t = 0:1/samplingFrequency:1;
                f_modulation = 0.5; % Modulating frequency
                x = sawtooth(2*pi*(100 + 50*sin(2*pi*f_modulation*t)).*t);
            elseif strcmpi(inputSignal, 'Rect pulse train with varying frequency')
                t = 0:1/samplingFrequency:1;
                f_pulse_train = linspace(50, 200, length(t)); % Varying pulse train frequency
                x = square(2*pi*f_pulse_train.*t);
            elseif strcmpi(inputSignal,'Square Wave with modulating Frequency')
                t = 0:1/samplingFrequency:1;
                f_modulation = 0.5; % Modulating frequency
                x = square(2*pi*(100 + 50*sin(2*pi*f_modulation*t)).*t);
            else
                % Handle unsupported filter type
                disp('Unsupported input signal selected.');
                return;
            end

            %Processing Input signal
            % Apply the designed filter to the input signal
            y = filter(b, a, x);

            %Displaying input and filtered signal
            plot(app.UIAxes2,t, x);
            title(app.UIAxes2,'Input Signal');
            xlabel(app.UIAxes2,'Time (s)');
            ylabel(app.UIAxes2,'Amplitude');
            grid(app.UIAxes2, 'on');
            
            plot(app.UIAxes3,t, y);
            title(app.UIAxes3,'Filtered Output');
            xlabel(app.UIAxes3,'Time (s)');
            ylabel(app.UIAxes3,'Amplitude');
            grid(app.UIAxes3, 'on');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FilterTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.LeftPanel);
            app.FilterTypeDropDown.Items = {'low-pass', 'high-pass', 'band-pass', 'band-stop'};
            app.FilterTypeDropDown.Value = 'low-pass';
            app.FsHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FsHzEditFieldLabel.Text = 'Fs(Hz)';
            app.FsHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.LeftPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FilterSpecificationsLabel = uilabel(app.LeftPanel);
            app.FilterSpecificationsLabel.Text = 'Filter Specifications';
            app.Fpass1EditFieldLabel = uilabel(app.LeftPanel);
            app.Fpass1EditFieldLabel.Text = 'Fpass1';
            app.Fpass1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Fstop1EditFieldLabel = uilabel(app.LeftPanel);
            app.Fstop1EditFieldLabel.Text = 'Fstop1';
            app.Fstop1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagnitudedBEditFieldLabel = uilabel(app.LeftPanel);
            app.MagnitudedBEditFieldLabel.Text = 'Magnitude(dB)';
            app.MagnitudedBEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DesignFilterButton = uibutton(app.LeftPanel, 'push');
            app.DesignFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFilter, true);
            app.DesignFilterButton.Text = 'Design Filter';
            app.FSimpleSinewaveEditFieldLabel = uilabel(app.LeftPanel);
            app.FSimpleSinewaveEditFieldLabel.Text = 'F Simple Sine wave';
            app.FSimpleSinewaveEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlltherestinputfunctionsarefixedLabel = uilabel(app.LeftPanel);
            app.AlltherestinputfunctionsarefixedLabel.Text = 'All the rest input functions are fixed';
            app.InputSignalDropDownLabel = uilabel(app.LeftPanel);
            app.InputSignalDropDownLabel.Text = 'Input Signal';
            app.InputSignalDropDown = uidropdown(app.LeftPanel);
            app.InputSignalDropDown.Items = {'Simple Sine Wave', 'Chirp Signal', 'Sine wave with increasing frequency', 'Sawtooth with modulating frequency', 'Rect pulse train with varying frequency', 'Square Wave with modulating Frequency'};
            app.InputSignalDropDown.Value = 'Simple Sine Wave';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end